classdef MapeandoColores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UPDATEButton           matlab.ui.control.Button
        FILTROSButtonGroup     matlab.ui.container.ButtonGroup
        F4                     matlab.ui.control.RadioButton
        F3                     matlab.ui.control.RadioButton
        F2                     matlab.ui.control.RadioButton
        F1                     matlab.ui.control.RadioButton
        PredefinedImagesLabel  matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        LoadButton             matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        RedAxes_2              matlab.ui.control.UIAxes
        BlueAxes_2             matlab.ui.control.UIAxes
        GreenAxes_2            matlab.ui.control.UIAxes
        ImageAxes_2            matlab.ui.control.UIAxes
        RedAxes                matlab.ui.control.UIAxes
        BlueAxes               matlab.ui.control.UIAxes
        GreenAxes              matlab.ui.control.UIAxes
        ImageAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        IMAGEN;
    end

    methods (Access = private)

        function updateimage(app,imagefile)
            
            
            v = app.F1.Value*1 + app.F2.Value*2 + app.F3.Value*3 + app.F4.Value*4;
            app.IMAGEN = imagefile;
            updateFilt(app,imagefile, v)

            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
            end

            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);

                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');

                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);

                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');

                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            % Get largest bin count
            maxr = max(histr.BinCounts);
            maxg = max(histg.BinCounts);
            maxb = max(histb.BinCounts);
            maxcount = max([maxr maxg maxb]);

            % Set y axes limits based on largest bin count
            app.RedAxes.YLim = [0 maxcount];
            app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.GreenAxes.YLim = [0 maxcount];
            app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.BlueAxes.YLim = [0 maxcount];
            app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
        end

        function updateFilt(app,imagefile,n)
           
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
            end

            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Division de canal
                    IM = double(im(:,:,1));


                    switch n
                        case 1
                            % Primer filtro
                            disp("Fun1")
                            R = @(t) funCol1(t);
                            G = @(t) t;
                            B = @(t) t;
                            
                            IRN = uint8(R(IM));
                            IGN = uint8(G(IM));
                            IBN = uint8(B(IM));

                        case 2
                            disp("Fun2")
                            % Segundo filtro
                            R = @(t) abs(t .* cos( t ) + t);
                            G = @(t) abs(t .* sin( t ) + t);
                            B = @(t) t;
                            
                            IRN = uint8(R(IM));
                            IGN = uint8(G(IM));
                            IBN = uint8(B(IM));
                            
                        case 3
                            disp("Fun3")
                            % Tercer filtro 
                            R = @(t) t;
                            G = @(t) t.*cos(t);
                            B = @(t) t.*sin(t);
                            
                            IRN = R(IM);
                            IGN = G(IRN);
                            IBN = B(IGN);

                            IRN = uint8(IRN);
                            IGN = uint8(IGN);
                            IBN = uint8(IBN);
                        case 4
                            % Cuatro filtro
                            R = @(t) funCol3(t);
                            G = @(t) funCol3(t);
                            B = @(t) funCol3(t);

                            IRN = uint8(R(IM));
                            IGN = uint8(G(IM));
                            IBN = uint8(B(IM));
                        otherwise
                            uialert(app.UIFigure, 'Se debe aplicar uno de los 4 filtros.', 'Image Error');
                            return;
                    end
                   
                    im = cat(3,IRN,IGN,IBN);

                    % Display the grayscale image
                    imagesc(app.ImageAxes_2,im);

                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes_2, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes_2, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes_2, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');

                case 3

                    % Division de canal
                    IR = double(im(:,:,1));
                    IG = double(im(:,:,2));
                    IB = double(im(:,:,3));
                    
                    IM = ( IR + IG + IB ) ./ 3;

                    switch n
                        case 1
                            % Primer filtro
                            R = @(t) funCol1(t);
                            G = @(t) t;
                            B = @(t) t;

                            IRN = uint8(R(IR));
                            IGN = uint8(G(IR));
                            IBN = uint8(B(IR));
                        case 2
                            % Segundo filtro
                            R = @(t) 3 .* cos(t) + t;
                            G = @(t) 2.*sin(t)+t;
                            B = @(t) abs(t-100);


                            IRN = uint8(R(IR));
                            IGN = uint8(G(IG));
                            IBN = uint8(B(IB));
                        case 3
                            % Tercer filtro
                            R = @(t) t;
                            G = @(t) t.*cos(t);
                            B = @(t) t.*sin(t);
                            
                            IRN = R(IR);
                            IGN = G(IRN);
                            IBN = B(IGN);

                            IRN = uint8(IRN);
                            IGN = uint8(IGN);
                            IBN = uint8(IBN);
                        case 4
                            % Cuatro filtro
                            R = @(t) funCol3(t);
                            G = @(t) funCol3(t);
                            B = @(t) funCol3(t);

                            IRN = uint8(R(IM));
                            IGN = uint8(G(IM));
                            IBN = uint8(B(IM));                      
                        otherwise
                            uialert(app.UIFigure, 'Se debe aplicar uno de los 4 filtros.', 'Image Error');
                            return;
                    end

                    L = 0:0.001:255;
                    L = L';
                    map = [R(L)./max(R(L)) G(L)./max(G(L)) B(L)./max(B(L))];
                    scatter3(app.UIAxes,map(:,1), map(:,2), map(:,3),4,map);
                    

                    % Reencuentro de canales :)
                    im = cat(3,IRN,IGN,IBN);

                    % Display the truecolor image
                    imagesc(app.ImageAxes_2,im);

                    % Plot the histograms
                    histr = histogram(app.RedAxes_2, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes_2, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes_2, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');

                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            % Get largest bin count
            maxr = max(histr.BinCounts);
            maxg = max(histg.BinCounts);
            maxb = max(histb.BinCounts);
            maxcount = max([maxr maxg maxb]);

            % Set y axes limits based on largest bin count
            app.RedAxes_2.YLim = [0 maxcount];
            app.RedAxes_2.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.GreenAxes_2.YLim = [0 maxcount];
            app.GreenAxes_2.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.BlueAxes_2.YLim = [0 maxcount];
            app.BlueAxes_2.YTick = round([0 maxcount/2 maxcount], 2, 'significant');

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');

            % Update the image and histograms
            updateimage(app, 'peppers.png');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
            end
        end

        % Button pushed function: UPDATEButton
        function UPDATEButtonPushed(app, event)
            v = app.F1.Value*1 + app.F2.Value*2 + app.F3.Value*3 + app.F4.Value*4;
            updateFilt(app,app.IMAGEN, v)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Mapeando Colores';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.GreenAxes = uiaxes(app.UIFigure);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, 'Pixels')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.BlueAxes = uiaxes(app.UIFigure);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, 'Pixels')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.RedAxes = uiaxes(app.UIFigure);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.ImageAxes_2 = uiaxes(app.UIFigure);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.GreenAxes_2 = uiaxes(app.UIFigure);
            title(app.GreenAxes_2, 'Green')
            xlabel(app.GreenAxes_2, 'Intensity')
            ylabel(app.GreenAxes_2, 'Pixels')
            app.GreenAxes_2.XLim = [0 255];
            app.GreenAxes_2.XTick = [0 128 255];
            app.BlueAxes_2 = uiaxes(app.UIFigure);
            title(app.BlueAxes_2, 'Blue')
            xlabel(app.BlueAxes_2, 'Intensity')
            ylabel(app.BlueAxes_2, 'Pixels')
            app.BlueAxes_2.XLim = [0 255];
            app.BlueAxes_2.XTick = [0 128 255];
            app.RedAxes_2 = uiaxes(app.UIFigure);
            title(app.RedAxes_2, 'Red')
            xlabel(app.RedAxes_2, 'Intensity')
            ylabel(app.RedAxes_2, 'Pixels')
            app.RedAxes_2.XLim = [0 255];
            app.RedAxes_2.XTick = [0 128 255];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Funcion de transformacion')
            xlabel(app.UIAxes, 'R')
            ylabel(app.UIAxes, 'G')
            zlabel(app.UIAxes, 'B')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Peppers', 'Corn'};
            app.DropDown.ItemsData = {'peppers.png', 'corn.tif'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'peppers.png';
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.FILTROSButtonGroup = uibuttongroup(app.UIFigure);
            app.FILTROSButtonGroup.Title = 'F I L T R O S';
            app.F1 = uiradiobutton(app.FILTROSButtonGroup);
            app.F1.Text = 'Filtro 3D antiguo';
            app.F1.Value = true;
            app.F2 = uiradiobutton(app.FILTROSButtonGroup);
            app.F2.Text = 'Filtro val. absoluto';
            app.F3 = uiradiobutton(app.FILTROSButtonGroup);
            app.F3.Text = 'Filtro Naranja';
            app.F4 = uiradiobutton(app.FILTROSButtonGroup);
            app.F4.Text = 'Filtro Grises Contrases';
            app.UPDATEButton = uibutton(app.UIFigure, 'push');
            app.UPDATEButton.ButtonPushedFcn = createCallbackFcn(app, @UPDATEButtonPushed, true);
            app.UPDATEButton.Text = 'U P D A T E';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MapeandoColores

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end